classdef Circular_Traj_Params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        circular_radius    matlab.ui.control.EditField
        circular_period    matlab.ui.control.EditField
        circular_duration  matlab.ui.control.EditField
        text7              matlab.ui.control.Label
        text8              matlab.ui.control.Label
        text9              matlab.ui.control.Label
        text12             matlab.ui.control.Label
        SetButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
                 app.MainApp = mainapp;
        end

        % Close request function: UIFigure
        function CircularTrajParamsCloseRequest(app, event)
            
            % Delete the Window
            delete(app)
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            radius = str2num(app.circular_radius.Value);
            duration = str2num(app.circular_duration.Value);
            period = str2num(app.circular_period.Value);
            [~,app.MainApp.payload_stop] = circle([], [], app.MainApp.payload_start(1:3),radius,period,duration);
            app.MainApp.TrajHdl = @circle;
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CircularTrajParamsCloseRequest, true);
            app.circular_radius = uieditfield(app.UIFigure, 'text');
            app.circular_radius.Tag = 'circular_radius';
            app.circular_radius.Value = '0.0';
            app.circular_period = uieditfield(app.UIFigure, 'text');
            app.circular_period.Tag = 'circular_period';
            app.circular_period.Value = '0.0';
            app.circular_duration = uieditfield(app.UIFigure, 'text');
            app.circular_duration.Tag = 'circular_duration';
            app.circular_duration.Value = '0.0';
            app.text7 = uilabel(app.UIFigure);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Radius';
            app.text8 = uilabel(app.UIFigure);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Period';
            app.text9 = uilabel(app.UIFigure);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Duration';
            app.text12 = uilabel(app.UIFigure);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Circular Trajectory Parameter Setup';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Circular_Traj_Params(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end