classdef Minderivative_Traj_Params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        text12                   matlab.ui.control.Label
        SetButton                matlab.ui.control.Button
        text3                    matlab.ui.control.Label
        SelectPayloadPathButton  matlab.ui.control.Button
        text3_2                  matlab.ui.control.Label
        SelectTrajectoryOptimizationOptionButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % MainApp
        payload_path = [0.0 0.0 0.0;
                        0.5 -0.5 0.25;
                        1.0 0.0 0.5;
                        1.5 -0.5 0.75;
                        2.0 0.0 1.0]; % Default payload path
        payload_path_file = 'config/payload_path/path1.csv';
        min_derivative_traj_optimization_option_file = '';
        max_diff = 6;
        max_exponent = 11;
        max_vel = 3;
        max_acc = 10;
        dim = 3;
        cor_wid = 0.5;
        nc = 10;
        cor_constraint = false;
        max_iteration = 300;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
                 
            app.MainApp = mainapp;
            
        end

        % Close request function: UIFigure
        function CircularTrajParamsCloseRequest(app, event)
            
            % Delete the Window
            delete(app)
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            
            if ~isempty(app.payload_path_file)
                app.payload_path = readmatrix(convertStringsToChars(app.payload_path_file));
            end
            [pathlength, ~] = size(app.payload_path);
            
            if ~isempty(app.min_derivative_traj_optimization_option_file)
                yaml = ReadYaml(app.min_derivative_traj_optimization_option_file);
                field = fieldnames(yaml);
            else
                field = struct([]);
            end
        
            %% Trajectory Optimization Option
            idx_max_diff = find(strcmp(field,'max_derivative_order'));
            if ~isempty(idx_max_diff)
                traj_constant.max_diff = yaml.max_derivative_order;
            else
                traj_constant.max_diff = app.max_diff;
            end
            
            idx_max_exponent = find(strcmp(field,'max_exponent'));
            if ~isempty(idx_max_exponent)
                traj_constant.max_exponent = yaml.max_exponent;
            else
                traj_constant.max_exponent = app.max_exponent;
            end
            
            idx_max_vel = find(strcmp(field,'max_velocity'));
            if ~isempty(idx_max_vel)
                traj_constant.max_vel = yaml.max_velocity;
            else
                traj_constant.max_vel = app.max_vel;
            end
            
            idx_max_acc = find(strcmp(field,'max_acceleration'));
            if ~isempty(idx_max_acc)
                traj_constant.max_acc = yaml.max_acceleration;
            else
                traj_constant.max_acc = app.max_acc;
            end
 
            idx_dim = find(strcmp(field,'dimension'));
            if ~isempty(idx_dim)
                traj_constant.dim = yaml.dimension;
            else
                traj_constant.dim = app.dim;
            end
                 
            idx_cor_wid = find(strcmp(field,'corridor_width'));
            if ~isempty(idx_cor_wid)
                traj_constant.cor_wid = yaml.corridor_width;
            else
                traj_constant.cor_wid = app.cor_wid;
            end
            
            idx_nc = find(strcmp(field,'number_of_points_to_check_in_corridor'));
            if ~isempty(idx_nc)
                traj_constant.nc = yaml.number_of_points_to_check_in_corridor;
            else
                traj_constant.nc = app.nc;
            end
            
            idx_cor_constraint = find(strcmp(field,'corridor_constraint_flag'));
            if ~isempty(idx_cor_constraint)
                traj_constant.cor_constraint = yaml.corridor_constraint_flag;
            else
                traj_constant.cor_constraint = app.cor_constraint;
            end
            
            idx_max_iteration = find(strcmp(field,'max_iteration'));
            if ~isempty(idx_max_iteration)
                traj_constant.max_iteration = yaml.max_iteration;
            else
                traj_constant.max_iteration = app.max_iteration;
            end
            
            traj_constant.num_coeff = traj_constant.max_exponent +1;
            traj_constant.total_traj_num = pathlength - 1;
            traj_constant.pt_num = pathlength;
            
            min_snap_traj_generator([], [], app.payload_path, traj_constant);
            app.MainApp.TrajHdl = @min_snap_traj_generator;
            app.MainApp.payload_stop = [app.payload_path(end,:),1,0,0,0]';
            close(app.UIFigure);
            
        end

        % Button pushed function: SelectPayloadPathButton
        function SelectPayloadPathButtonPushed(app, event)
            
            [file,path] = uigetfile('config/payload_path/*.csv');
            app.payload_path_file = append(path,file);
            
        end

        % Button pushed function: 
        % SelectTrajectoryOptimizationOptionButton
        function SelectTrajectoryOptimizationOptionButtonPushed(app, event)
            
            [file,path] = uigetfile('config/min_derivative_traj_optimization_option/*.yaml');
            app.min_derivative_traj_optimization_option_file = append(path,file);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CircularTrajParamsCloseRequest, true);
            app.text12 = uilabel(app.UIFigure);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Minimum Derivative Trajectory Parameter Setup';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.text3 = uilabel(app.UIFigure);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Payload Path';
            app.SelectPayloadPathButton = uibutton(app.UIFigure, 'push');
            app.SelectPayloadPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPayloadPathButtonPushed, true);
            app.SelectPayloadPathButton.Text = 'Select Payload Path';
            app.text3_2 = uilabel(app.UIFigure);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = 'Optimization Option';
            app.SelectTrajectoryOptimizationOptionButton = uibutton(app.UIFigure, 'push');
            app.SelectTrajectoryOptimizationOptionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrajectoryOptimizationOptionButtonPushed, true);
            app.SelectTrajectoryOptimizationOptionButton.Text = {'Select Trajectory Optimization Option'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minderivative_Traj_Params(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end