classdef RotorTM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        SelectAttachMechanismParamFileButton_2  matlab.ui.control.Button
        text3_4                       matlab.ui.control.Label
        SelectInitialConditionFileButton  matlab.ui.control.Button
        text3_3                       matlab.ui.control.Label
        SelectUAVControllerParamFileButton  matlab.ui.control.Button
        SelectPayloadControllerParamFileButton  matlab.ui.control.Button
        text3_2                       matlab.ui.control.Label
        text2_3                       matlab.ui.control.Label
        SetupParam_buttion            matlab.ui.control.Button
        text2_2                       matlab.ui.control.Label
        SelectUAVParamFileButton      matlab.ui.control.Button
        TrajectoryGenereatorDropDown  matlab.ui.control.DropDown
        TrajectoryGenereatorDropDownLabel  matlab.ui.control.Label
        SelectPayloadParamFileButton  matlab.ui.control.Button
        run_button                    matlab.ui.control.Button
        text3                         matlab.ui.control.Label
        text2                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        uav_params_path = 'config/uav_params/snapdragonfly.yaml';
        uav_controller_params_path = 'config/control_params/dragonfly_control_gains.yaml';
        payload_params_path = 'config/load_params/triangular_payload.yaml';
        payload_controller_params_path = 'config/control_params/triangular_payload_cooperative_cable_gains.yaml';
        mechanism_params_path = 'config/attach_mechanism/3_robots_cable_mechanism.yaml';
        initial_condition_path = 'config/initial_condition/3_robots_triangular_payload_initial_condition.yaml';
        pl_params;
        uav_params;
        map;        
    end
    
    properties (Access = public)
        payload_start = [0.0 0.0 0.0 1.0 0.0 0.0 0.0]';
        payload_stop = [1.0 0.0 0.0 1.0 0.0 0.0 0.0]';
        robot_start = {};
        TrajHdl;    % Trajectory Handle;
        TrajParams; % Trajectory Parameters;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function simple_gui_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to simple_gui (see VARARGIN)
 
            % Start the rosmaster
            rosshutdown;
            rosinit;
            
            % Choose default command line output for simple_gui
            handles.output = hObject;
            
            % object type
            handles.is_rigidbody = false;
            handles.is_pointmass = false;
            
            % line traj
            handles.is_line_traj = false;
            handles.x = 0;
            handles.y = 0;
            handles.z = 0;
            
            % circle traj
            handles.is_circle_traj = false;
            handles.radius = 0;
            handles.period = 0;
            handles.duration = 0;
            
            % store whether all input to trajectory are valid
            handles.is_all_valid = false;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function
        function circular_duration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to circular_duration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of circular_duration as text
            %        str2double(get(hObject,'String')) returns contents of circular_duration as a double
            
            try
                duration = str2double(get(hObject, 'String'));
            catch
                %     handles.is_all_valid = false;
            end
            handles.duration = duration;
            guidata(hObject, handles);
        end

        % Callback function
        function circular_period_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to circular_period (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of circular_period as text
            %        str2double(get(hObject,'String')) returns contents of circular_period as a double
            try
                period = str2double(get(hObject, 'String'));
            catch
                %     handles.is_all_valid = false;
            end
            handles.period = period;
            guidata(hObject, handles);
        end

        % Callback function
        function circular_radius_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to circular_radius (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of circular_radius as text
            %        str2double(get(hObject,'String')) returns contents of circular_radius as a double
            
            try
                radius = str2double(get(hObject, 'String'));
            catch
                %     handles.is_all_valid = false;
            end
            handles.radius = radius;
            guidata(hObject, handles);
        end

        % Callback function
        function pl_init_x_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to line_x (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of line_x as text
            %        str2double(get(hObject,'String')) returns contents of line_x as a double
            
            try
                x = str2double(get(hObject, 'String'));
            catch
                %     handles.is_all_valid = false;
            end
            handles.x = x;
            guidata(hObject, handles);
        end

        % Callback function
        function pl_init_y_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to line_y (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of line_y as text
            %        str2double(get(hObject,'String')) returns contents of line_y as a double
            
            try
                y = str2double(get(hObject, 'String'));
            catch
                %     handles.is_all_valid = false;
            end
            handles.y = y;
            guidata(hObject, handles);
        end

        % Callback function
        function pl_init_z_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to line_z (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of line_z as text
            %        str2double(get(hObject,'String')) returns contents of line_z as a double
            
            try
                z = str2double(get(hObject, 'String'));
            catch
                %     handles.is_all_valid = false;
            end
            handles.z = z;
            guidata(hObject, handles);
        end

        % Callback function
        function uibuttongroup_object_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uibuttongroup_object
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            switch get(hObject, 'Tag')
                case 'rigidbody'
                    handles.is_rigidbody = true;
                    handles.is_pointmass = false;
                case 'pointmass'
                    handles.is_rigidbody = false;
                    handles.is_pointmass = true;
                    
            end
            guidata(hObject, handles);
        end

        % Callback function
        function uibuttongroup_traj_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uibuttongroup_traj
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            switch get(hObject, 'Tag')
                case 'line_traj_radio'
                    handles.is_line_traj = true;
                    handles.is_circle_traj = false;
                case 'cir_traj_radio'
                    handles.is_line_traj = false;
                    handles.is_circle_traj = true;
                    
            end
            guidata(hObject, handles);
            disp(handles.is_circle_traj);
        end

        % Button pushed function: SelectPayloadParamFileButton
        function SelectPayloadParamFileButtonPushed(app, event)
            [file,path] = uigetfile('config/load_params/*.yaml');
            app.payload_params_path = append(path,file);
        end

        % Button pushed function: SelectUAVParamFileButton
        function SelectUAVParamFileButtonPushed(app, event)
            [file,path] = uigetfile('config/uav_params/*.yaml');
            app.uav_params_path = append(path,file);
        end

        % Button pushed function: run_button
        function run_buttonButtonPushed(app, event)
            
            if app.pl_params.mechanism_type == "Cable"
                if app.pl_params.payload_type == "Rigid Body"
                    [data, data_des, time] = cooperative_hybrid_test_trajectory(app.robot_start, app.payload_start, app.payload_stop, true, app.TrajHdl, app.pl_params,app.uav_params); % with visualization
                elseif app.pl_params.payload_type == "Point Mass"
                    [data, data_des, time] = hybrid_test_trajectory(app.robot_start, app.payload_start, app.payload_stop, true, app.TrajHdl,app.pl_params,app.uav_params); % with visualization
                else 
                    error("The payload type is invalid, Abort");
                end
            elseif app.pl_params.mechanism_type == "Rigid Link"
                [data, data_des, time] = rigid_links_test_trajectory(app.robot_start, app.payload_start, app.payload_stop, true, app.TrajHdl, app.pl_params, app.uav_params); % with visualization
            else
                error("The attach mechanism type is invalid, Abort");
            end
          
        end

        % Button pushed function: SetupParam_buttion
        function SetupParam_buttionButtonPushed(app, event)
                     
            if isempty(app.uav_params_path) || isempty(app.payload_params_path)
                fprintf("The payload and UAV parameters are not set up.\n");
                return;
            else
                app.robot_start = {};
                
                %% Set up UAV Parameters
                app.uav_params = read_uav_params(convertStringsToChars(app.uav_params_path));
                app.uav_params = read_control_gains_params(convertStringsToChars(app.uav_controller_params_path),app.uav_params);
                
                %% Set up Payload Parameters
                app.pl_params = robot_team_setup(convertStringsToChars(app.payload_params_path),...
                                                 convertStringsToChars(app.uav_params_path),...
                                                 convertStringsToChars(app.mechanism_params_path));
                app.pl_params = read_control_gains_params(convertStringsToChars(app.payload_controller_params_path),app.pl_params);
                
                %% Set up initial conditions for robots
                init_condition_yaml = ReadYaml(convertStringsToChars(app.initial_condition_path));
                field = fieldnames(init_condition_yaml);

                for i = 1:app.pl_params.nquad
                    app.robot_start{i} = [init_condition_yaml.robot_start{i}.x,init_condition_yaml.robot_start{i}.y,init_condition_yaml.robot_start{i}.z];
                end
                
                %% Set up initial condition for payload
                app.payload_start = [init_condition_yaml.payload_start.x,...
                                     init_condition_yaml.payload_start.y,...
                                     init_condition_yaml.payload_start.z,...
                                     init_condition_yaml.payload_start.qw,...
                                     init_condition_yaml.payload_start.qx,...
                                     init_condition_yaml.payload_start.qy,...
                                     init_condition_yaml.payload_start.qz]';
                
                %% Set up terminal condition for payload (Optional) 
                % Can be overwritten if the trajectory returns payload_stop)
                idx_payload_stop = find(strcmp(field,'payload_stop'));
                if ~isempty(idx_payload_stop)
                app.payload_stop = [init_condition_yaml.payload_stop.x,...
                                    init_condition_yaml.payload_stop.y,...
                                    init_condition_yaml.payload_stop.z,...
                                    init_condition_yaml.payload_stop.qw,...
                                    init_condition_yaml.payload_stop.qx,...
                                    init_condition_yaml.payload_stop.qy,...
                                    init_condition_yaml.payload_stop.qz]';                    
                end
                
                %% Initialize Trajectory Handle
                TrajGen = app.TrajectoryGenereatorDropDown.Value;
                if TrajGen == "Circle"
                    Circular_Traj_Params(app);
                elseif TrajGen == "Min_Derivative"
                    Minderivative_Traj_Params(app);
                elseif TrajGen == "Custom"
                    [file,path] = uigetfile('traj/*.m');
                end
                
            end
        end

        % Button pushed function: SelectPayloadControllerParamFileButton
        function SelectPayloadControllerParamFileButtonPushed(app, event)
            [file,path] = uigetfile('config/control_params/*.yaml');
            app.payload_controller_params_path= append(path,file);
        end

        % Button pushed function: SelectUAVControllerParamFileButton
        function SelectUAVControllerParamFileButtonPushed(app, event)
            [file,path] = uigetfile('config/control_params/*.yaml');
            app.uav_controller_params_path= append(path,file);          
        end

        % Button pushed function: SelectInitialConditionFileButton
        function SelectInitialConditionFileButtonPushed(app, event)
            [file,path] = uigetfile('config/initial_condition/*.yaml');
            app.initial_condition_path = append(path,file);
        end

        % Button pushed function: SelectAttachMechanismParamFileButton_2
        function SelectAttachMechanismParamFileButton_2Pushed(app, event)
            [file,path] = uigetfile('config/attach_mechanism/*.yaml');
            app.mechanism_params_path = append(path,file);            
        end

        % Value changed function: TrajectoryGenereatorDropDown
        function TrajectoryGenereatorDropDownValueChanged(app, event)
            value = app.TrajectoryGenereatorDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'A Simple GUI';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'UAV Params';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Payload Params';
            app.run_button = uibutton(app.figure1, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
            app.run_button.Tag = 'run_button';
            app.run_button.Text = 'Run';
            app.SelectPayloadParamFileButton = uibutton(app.figure1, 'push');
            app.SelectPayloadParamFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPayloadParamFileButtonPushed, true);
            app.SelectPayloadParamFileButton.Text = 'Select Payload Param File';
            app.TrajectoryGenereatorDropDownLabel = uilabel(app.figure1);
            app.TrajectoryGenereatorDropDownLabel.Text = 'Trajectory Genereator';
            app.TrajectoryGenereatorDropDown = uidropdown(app.figure1);
            app.TrajectoryGenereatorDropDown.Items = {'Circle', 'Min_Derivative'};
            app.TrajectoryGenereatorDropDown.ValueChangedFcn = createCallbackFcn(app, @TrajectoryGenereatorDropDownValueChanged, true);
            app.TrajectoryGenereatorDropDown.Value = 'Circle';
            app.SelectUAVParamFileButton = uibutton(app.figure1, 'push');
            app.SelectUAVParamFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUAVParamFileButtonPushed, true);
            app.SelectUAVParamFileButton.Text = 'Select UAV Param File';
            app.text2_2 = uilabel(app.figure1);
            app.text2_2.Tag = 'text2';
            app.text2_2.Text = 'RotorTM: Simulator for Aerial Transportation and Manipulation ';
            app.SetupParam_buttion = uibutton(app.figure1, 'push');
            app.SetupParam_buttion.ButtonPushedFcn = createCallbackFcn(app, @SetupParam_buttionButtonPushed, true);
            app.SetupParam_buttion.Tag = 'run_button';
            app.SetupParam_buttion.Text = 'Setup Parameters';
            app.text2_3 = uilabel(app.figure1);
            app.text2_3.Tag = 'text2';
            app.text2_3.Text = 'UAV Controller Params';
            app.text3_2 = uilabel(app.figure1);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = 'Payload Controller Params';
            app.SelectPayloadControllerParamFileButton = uibutton(app.figure1, 'push');
            app.SelectPayloadControllerParamFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPayloadControllerParamFileButtonPushed, true);
            app.SelectPayloadControllerParamFileButton.Text = 'Select Payload Controller Param File';
            app.SelectUAVControllerParamFileButton = uibutton(app.figure1, 'push');
            app.SelectUAVControllerParamFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUAVControllerParamFileButtonPushed, true);
            app.SelectUAVControllerParamFileButton.Text = 'Select UAV Controller Param File';
            app.text3_3 = uilabel(app.figure1);
            app.text3_3.Tag = 'text3';
            app.text3_3.Text = 'Initial Condition';
            app.SelectInitialConditionFileButton = uibutton(app.figure1, 'push');
            app.SelectInitialConditionFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInitialConditionFileButtonPushed, true);
            app.SelectInitialConditionFileButton.Text = 'Select Initial Condition File';
            app.text3_4 = uilabel(app.figure1);
            app.text3_4.Tag = 'text3';
            app.text3_4.Text = 'Attach Mechanism Params';
            app.SelectAttachMechanismParamFileButton_2 = uibutton(app.figure1, 'push');
            app.SelectAttachMechanismParamFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAttachMechanismParamFileButton_2Pushed, true);
            app.SelectAttachMechanismParamFileButton_2.Text = 'Select Attach Mechanism Param File';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RotorTM_GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)simple_gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end